classdef neuronImageCounterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NeuronImageCounterLabel    matlab.ui.control.Label
        MinimumNeuronPixelSizeEditFieldLabel  matlab.ui.control.Label
        neuronPixelSize            matlab.ui.control.NumericEditField
        PickImageButton            matlab.ui.control.Button
        AnalyzeButton              matlab.ui.control.Button
        imageDisplayTypeChoice     matlab.ui.container.ButtonGroup
        diffButton                 matlab.ui.control.RadioButton
        montageButton              matlab.ui.control.RadioButton
        blendButton                matlab.ui.control.RadioButton
        falsecolorButton           matlab.ui.control.RadioButton
        checkerboardButton         matlab.ui.control.RadioButton
        PathToImageLabel           matlab.ui.control.Label
        SensitivityEditFieldLabel  matlab.ui.control.Label
        SensitivityField           matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        PathToImage % Description
    end


    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            current_option = get(app.imageDisplayTypeChoice, 'SelectedObject');
            if ~isempty(app.PathToImage)
                countNeurons(app.PathToImage, app.neuronPixelSize.Value, current_option.Text, app.SensitivityField.Value);
            end
        end

        % Button pushed function: PickImageButton
        function PickImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png';'*.jpg'; '*.tif'});
            app.PathToImage = [path file];
            app.PathToImageLabel.Text = file;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NeuronImageCounterLabel = uilabel(app.UIFigure);
            app.NeuronImageCounterLabel.Text = 'Neuron Image Counter';
            app.MinimumNeuronPixelSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumNeuronPixelSizeEditFieldLabel.Text = 'Minimum Neuron Pixel Size';
            app.neuronPixelSize = uieditfield(app.UIFigure, 'numeric');
            app.neuronPixelSize.Value = 300;
            app.PickImageButton = uibutton(app.UIFigure, 'push');
            app.PickImageButton.ButtonPushedFcn = createCallbackFcn(app, @PickImageButtonPushed, true);
            app.PickImageButton.Text = 'Pick Image';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.imageDisplayTypeChoice = uibuttongroup(app.UIFigure);
            app.imageDisplayTypeChoice.Title = 'Final Image Display Type';
            app.diffButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.diffButton.Text = 'diff';
            app.diffButton.Value = true;
            app.montageButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.montageButton.Text = 'montage';
            app.blendButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.blendButton.Text = 'blend';
            app.falsecolorButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.falsecolorButton.Text = 'falsecolor';
            app.checkerboardButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.checkerboardButton.Text = 'checkerboard';
            app.PathToImageLabel = uilabel(app.UIFigure);
            app.PathToImageLabel.Text = 'Path To Image';
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityField = uieditfield(app.UIFigure, 'numeric');
            app.SensitivityField.Value = 0.01;
        end
    end

    methods (Access = public)

        % Construct app
        function app = neuronImageCounterGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end