classdef neuronImageCounterTIFAdjusted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NeuronImageCounterforTIFfilesLabel  matlab.ui.control.Label
        MinimumNeuronPixelSizeEditFieldLabel  matlab.ui.control.Label
        neuronPixelSize             matlab.ui.control.NumericEditField
        PickImageButton             matlab.ui.control.Button
        AnalyzeButton               matlab.ui.control.Button
        imageDisplayTypeChoice      matlab.ui.container.ButtonGroup
        diffButton                  matlab.ui.control.RadioButton
        montageButton               matlab.ui.control.RadioButton
        blendButton                 matlab.ui.control.RadioButton
        falsecolorButton            matlab.ui.control.RadioButton
        checkerboardButton          matlab.ui.control.RadioButton
        PathToImageLabel            matlab.ui.control.Label
        ThresholdAdjustmentLabel    matlab.ui.control.Label
        SensitivityField            matlab.ui.control.NumericEditField
        TIFSlideEditFieldLabel      matlab.ui.control.Label
        TIFSlideEditField           matlab.ui.control.NumericEditField
        CellMarkerColorButtonGroup  matlab.ui.container.ButtonGroup
        greenButton                 matlab.ui.control.RadioButton
        blueButton                  matlab.ui.control.RadioButton
    end


    properties (Access = public)
        PathToImage % Description
    end


    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            current_option = get(app.imageDisplayTypeChoice, 'SelectedObject');
            current_color = get(app.CellMarkerColorButtonGroup, 'SelectedObject');
            if ~isempty(app.PathToImage)
                countNeuronsTIF(app.PathToImage, app.neuronPixelSize.Value, app.TIFSlideEditField.Value, current_color.Text, app.SensitivityField.Value, current_option.Text);
            end
        end

        % Button pushed function: PickImageButton
        function PickImageButtonPushed(app, event)
            [file, path] = uigetfile('*.tif');
            app.PathToImage = [path file];
            app.PathToImageLabel.Text = file;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NeuronImageCounterforTIFfilesLabel = uilabel(app.UIFigure);
            app.NeuronImageCounterforTIFfilesLabel.Text = 'Neuron Image Counter for TIF files';
            app.MinimumNeuronPixelSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumNeuronPixelSizeEditFieldLabel.Text = 'Minimum Neuron Pixel Size';
            app.neuronPixelSize = uieditfield(app.UIFigure, 'numeric');
            app.neuronPixelSize.Value = 15;
            app.PickImageButton = uibutton(app.UIFigure, 'push');
            app.PickImageButton.ButtonPushedFcn = createCallbackFcn(app, @PickImageButtonPushed, true);
            app.PickImageButton.Text = 'Pick Image';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.imageDisplayTypeChoice = uibuttongroup(app.UIFigure);
            app.imageDisplayTypeChoice.Title = 'Final Image Display Type';
            app.diffButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.diffButton.Text = 'diff';
            app.diffButton.Value = true;
            app.montageButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.montageButton.Text = 'montage';
            app.blendButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.blendButton.Text = 'blend';
            app.falsecolorButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.falsecolorButton.Text = 'falsecolor';
            app.checkerboardButton = uiradiobutton(app.imageDisplayTypeChoice);
            app.checkerboardButton.Text = 'checkerboard';
            app.PathToImageLabel = uilabel(app.UIFigure);
            app.PathToImageLabel.Text = 'Path To Image';
            app.ThresholdAdjustmentLabel = uilabel(app.UIFigure);
            app.ThresholdAdjustmentLabel.Text = 'Threshold Adjustment';
            app.SensitivityField = uieditfield(app.UIFigure, 'numeric');
            app.SensitivityField.Value = 0.01;
            app.TIFSlideEditFieldLabel = uilabel(app.UIFigure);
            app.TIFSlideEditFieldLabel.Text = 'TIF Slide';
            app.TIFSlideEditField = uieditfield(app.UIFigure, 'numeric');
            app.TIFSlideEditField.Value = 1;
            app.CellMarkerColorButtonGroup = uibuttongroup(app.UIFigure);
            app.CellMarkerColorButtonGroup.Title = 'Cell Marker Color';
            app.greenButton = uiradiobutton(app.CellMarkerColorButtonGroup);
            app.greenButton.Text = 'green';
            app.greenButton.Value = true;
            app.blueButton = uiradiobutton(app.CellMarkerColorButtonGroup);
            app.blueButton.Text = 'blue';
        end
    end

    methods (Access = public)

        % Construct app
        function app = neuronImageCounterTIFAdjusted

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end